classdef Design1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        SignalNameEditField       matlab.ui.control.EditField
        SignalNameEditFieldLabel  matlab.ui.control.Label
        SamplesField              matlab.ui.control.NumericEditField
        SamplesEditFieldLabel     matlab.ui.control.Label
        RangeEditFieldLabel       matlab.ui.control.Label
        AddNewSignalLabel         matlab.ui.control.Label
        SignalTypeDropDown        matlab.ui.control.DropDown
        function_label            matlab.ui.control.Label
        a_slider                  matlab.ui.control.Slider
        b_slider                  matlab.ui.control.Slider
        c_slider                  matlab.ui.control.Slider
        d_slider                  matlab.ui.control.Slider
        aLabel_4                  matlab.ui.control.Label
        aLabel_3                  matlab.ui.control.Label
        aLabel_2                  matlab.ui.control.Label
        aLabel                    matlab.ui.control.Label
        MinRangeField             matlab.ui.control.NumericEditField
        tLabel                    matlab.ui.control.Label
        MaxRangeField             matlab.ui.control.NumericEditField
        AddSignalButton           matlab.ui.control.Button
        SignalTypeDropDownLabel   matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        freqAxes                  matlab.ui.control.UIAxes
        timeAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Global parameters
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Signal Array counter
        i;

        SignalArray;
        
        % slider parameters
        a_param
        b_param
        c_param
        d_param
        e_param

        % sampling parameters
        Fs  % Sampling Rate
        T   % Sampling Period
        
        % Time domain
        min_t
        max_t
        
        t   % Time vector
        X   % Time domain signal vector

        % Frequency domain
        f   % frequency domain signal vector
        P1  % ??????????????????????????????
    end
    
    % Methods that aren't tied to an event
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Functions for updating signal
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Update the a,b,c,d silder parameters
        function updateSignalPram(app)
            app.a_param = app.a_slider.Value;
            app.b_param = app.b_slider.Value;
            app.c_param = app.c_slider.Value;
            app.d_param = app.d_slider.Value;
        end

        % Updated Sampling Rate
        function calcSampling(app)
            app.Fs = app.SamplesField.Value;
            app.T = 1/app.Fs;
        end
        
        % Update Range of signal
        function calcSignalRange(app)
            app.min_t = app.MinRangeField.Value;
            app.max_t = app.MaxRangeField.Value;

            app.t = (app.min_t:app.max_t)*app.T;        % Time vector
        end

        % Calculate both Signal
        function calculateSignal(app)
            %Note: Using hertz here.
            app.X = app.a_param * sin(app.b_param * 2 * pi * app.t + app.c_param) + app.d_param;
            
            Y = fft(app.X);
            P2 = abs(Y/app.max_t);
            app.P1 = P2(1:app.max_t/2+1);
            app.P1(2:end-1) = 2*app.P1(2:end-1);
            %Convert to dB
            app.P1 = 20 * log10(app.P1);
            app.f = app.Fs*(0:(app.max_t/2))/app.max_t;
        end

        % Rendering Signals
        function updateDisplay(app)
            cla(app.timeAxes);
            cla(app.freqAxes);
            hold(app.timeAxes,'on');
            hold(app.freqAxes,'on');

            if ~isempty(app.SignalArray)
                for j = 1:length(app.SignalArray)
                    plot(app.timeAxes,app.SignalArray(j).t,app.SignalArray(j).X);
                    plot(app.freqAxes, app.SignalArray(j).f, app.SignalArray(j).P1);
                end
            end
            % hold(app.timeAxes,'off');
            % hold(app.freqAxes,'off');
            grid(app.timeAxes, 'on');
            grid(app.freqAxes, 'on');
            xlabel(app.timeAxes, 'Time (s)');
            ylabel(app.timeAxes, 'Amplitude (V)');

            xlabel(app.freqAxes, 'Frequency (Hz)');
            ylabel(app.freqAxes, 'Amplitude (dB)');
            plot(app.timeAxes,app.t,app.X)
            plot(app.freqAxes, app.f, app.P1);
        end

        % Refresh All
        function RefreshEntireSignal(app)
           updateSignalPram(app)
           calcSampling(app)
           calcSignalRange(app)
           calculateSignal(app)
           updateDisplay(app)
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Add new Signal
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function AddnewSignal(app)
            % Saving Signal Parameters
            % app.SignalArray(end+1).Fs = app.Fs;

            app.SignalArray(app.i).Fs = app.Fs;
            app.SignalArray(app.i).T = app.T;

            app.SignalArray(app.i).t = app.t;
            app.SignalArray(app.i).X = app.X;          

            app.SignalArray(app.i).f = app.f;
            app.SignalArray(app.i).P1 = app.P1;

            app.SignalArray(app.i).name = app.AddNewSignalLabel.Text;
            app.i = app.i + 1;
            updateDisplay(app)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.i = 1;
            
            RefreshEntireSignal(app)
            updateDisplay(app)
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            value = app.SignalTypeDropDown.Value;
            if value == "Sine"
                   app.function_label.Text = "y = a * sin(b * t + c) + d";
                   app.a_slider.Visible = true;
                   app.b_slider.Visible = true;
                   app.c_slider.Visible = true;
                   app.d_slider.Visible = true;
            elseif value == "Square"
                app.function_label.Text = "y = square(a * t) [b <= y <= c]";
                app.a_slider.Visible = true;
                app.b_slider.Visible = true;
                app.c_slider.Visible = true;
                app.d_slider.Visible = false;
            end
        end

        % Button pushed function: AddSignalButton
        function AddSignalButtonPushed(app, event)
            AddnewSignal(app);
        end

        % Callback function: MaxRangeField, MinRangeField, SamplesField, 
        % ...and 5 other components
        function SignalVariableChanged(app, event)
            RefreshEntireSignal(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {535, 535};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {294, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SignalTypeDropDownLabel = uilabel(app.LeftPanel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.AddSignalButton = uibutton(app.LeftPanel, 'push');
            app.AddSignalButton.ButtonPushedFcn = createCallbackFcn(app, @AddSignalButtonPushed, true);
            app.AddSignalButton.Text = 'Add Signal';
            app.MaxRangeField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxRangeField.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.MaxRangeField.Value = 1500;
            app.tLabel = uilabel(app.LeftPanel);
            app.tLabel.Text = '< t <';
            app.MinRangeField = uieditfield(app.LeftPanel, 'numeric');
            app.MinRangeField.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.aLabel = uilabel(app.LeftPanel);
            app.aLabel.Text = 'a';
            app.aLabel_2 = uilabel(app.LeftPanel);
            app.aLabel_2.Text = 'b';
            app.aLabel_3 = uilabel(app.LeftPanel);
            app.aLabel_3.Text = 'c';
            app.aLabel_4 = uilabel(app.LeftPanel);
            app.aLabel_4.Text = 'd';
            app.d_slider = uislider(app.LeftPanel);
            app.d_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.c_slider = uislider(app.LeftPanel);
            app.c_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.b_slider = uislider(app.LeftPanel);
            app.b_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.b_slider.Value = 10;
            app.a_slider = uislider(app.LeftPanel);
            app.a_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.a_slider.ValueChangingFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.a_slider.Value = 1;
            app.function_label = uilabel(app.LeftPanel);
            app.function_label.Text = 'y = a*sin(b*x + c) + d';
            app.SignalTypeDropDown = uidropdown(app.LeftPanel);
            app.SignalTypeDropDown.Items = {'Sine', 'Square', 'Sawtooth', 'Triangle'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'Sine';
            app.AddNewSignalLabel = uilabel(app.LeftPanel);
            app.AddNewSignalLabel.Text = 'Add New Signal';
            app.RangeEditFieldLabel = uilabel(app.LeftPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.SamplesEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplesField.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.SamplesField.Value = 3000;
            app.SignalNameEditFieldLabel = uilabel(app.LeftPanel);
            app.SignalNameEditFieldLabel.Text = 'Signal Name';
            app.SignalNameEditField = uieditfield(app.LeftPanel, 'text');
            app.RightPanel = uipanel(app.GridLayout);
            app.timeAxes = uiaxes(app.RightPanel);
            title(app.timeAxes, 'Time Domain')
            xlabel(app.timeAxes, 'X')
            ylabel(app.timeAxes, 'Y')
            zlabel(app.timeAxes, 'Z')
            app.freqAxes = uiaxes(app.RightPanel);
            title(app.freqAxes, 'Frequency Domain')
            xlabel(app.freqAxes, 'X')
            ylabel(app.freqAxes, 'Y')
            zlabel(app.freqAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Design1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end